classdef numerical_solution_series_rlc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image                 matlab.ui.control.Image
        w0_EditField          matlab.ui.control.NumericEditField
        FrequencyradsecLabel  matlab.ui.control.Label
        C_EditField           matlab.ui.control.NumericEditField
        CapcitanceuFLabel     matlab.ui.control.Label
        L_EditField           matlab.ui.control.NumericEditField
        InductancemHLabel     matlab.ui.control.Label
        R_EditField           matlab.ui.control.NumericEditField
        R_Slider              matlab.ui.control.Slider
        ResistcanceLabel      matlab.ui.control.Label
        w0_Slider             matlab.ui.control.Slider
        C_Slider              matlab.ui.control.Slider
        L_Slider              matlab.ui.control.Slider
        SimulateButton        matlab.ui.control.Button
        rlc_model             matlab.ui.control.Image
        UIAxes_Combined       matlab.ui.control.UIAxes
        UIAxes_VC             matlab.ui.control.UIAxes
        UIAxes_VL             matlab.ui.control.UIAxes
        UIAxes_VR             matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: R_Slider
        function R_SliderValueChanging(app, event)
            changingValue = event.Value;
            app.R_EditField.Value = round(changingValue,2);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)

            % intialize value from the edit field or the sliders
            R_val = app.R_EditField.Value;
            w_0 = app.w0_EditField.Value;

            L_input = app.L_EditField.Value;
            L_val = L_input * 1e-3;

            C_input = app.C_EditField.Value;
            C_val = C_input * 1e-6;

            % implement variables to base workspace.
            assignin('base', 'R_val', R_val);
            assignin('base', 'L_val', L_val);
            assignin('base', 'C_val', C_val);
            assignin('base', 'w_0', w_0);

            % calculate 5 cycles and dynamic step
            T = (2*pi) / w_0;
            sim_time = 5*T;
            max_step = T / 300; 

           % run simulink from code
           simModelName = 'series_rlc_model';
           simOut = sim(simModelName, 'StopTime', num2str(sim_time), 'MaxStep', num2str(max_step));
           
           % try to extract all data from Outports
           % extract time vector
           time_vec = simOut.tout(:);            
           % extract data from the blocks
           VR_data = simOut.VR_out(:);
           VC_data = simOut.VC_out(:);
           VL_data = simOut.VL_out(:);

           % clearing graphs before plotting again
           cla(app.UIAxes_VR);
           cla(app.UIAxes_VL);
           cla(app.UIAxes_VC);
           cla(app.UIAxes_Combined);
          

           % plot graphs 3 subplots.
           plot(app.UIAxes_VR, time_vec, VR_data,'LineWidth',1.5, Color ='g');
           xlim(app.UIAxes_VR, [0 sim_time]);

           plot(app.UIAxes_VL, time_vec, VL_data,'LineWidth',1.5, Color ='b');
           xlim(app.UIAxes_VL, [0 sim_time]);

           plot(app.UIAxes_VC, time_vec, VC_data,'LineWidth',1.5, Color = 'y');
           xlim(app.UIAxes_VC, [0 sim_time]);

           % plot all of them in one graph
           plot(app.UIAxes_Combined, time_vec, VR_data,'LineWidth',1.5, Color ='g');
           hold(app.UIAxes_Combined,'on');
           plot(app.UIAxes_Combined, time_vec, VL_data,'LineWidth',1.5, Color ='b');
           plot(app.UIAxes_Combined, time_vec, VC_data,'LineWidth',1.5, Color = 'y');
           hold(app.UIAxes_Combined, 'off');
           xlim(app.UIAxes_Combined, [0 sim_time]);

           lgd = legend(app.UIAxes_Combined, {'V_{R} (Resistor)', 'V_{L} (Inductor)','V_{C} (Capacitor)'});
           lgd.TextColor = 'w';

        end

        % Value changing function: C_Slider
        function C_SliderValueChanging(app, event)
            changingValue = event.Value;
            app.C_EditField.Value = round(changingValue,2);
        end

        % Value changing function: L_Slider
        function L_SliderValueChanging(app, event)
            changingValue = event.Value;
            app.L_EditField.Value = round(changingValue,2);
        end

        % Value changing function: w0_Slider
        function w0_SliderValueChanging(app, event)
            changingValue = event.Value;
            app.w0_EditField.Value = round(changingValue,2);
            
        end

        % Value changed function: R_Slider
        function R_SliderValueChanged(app, event)
            value = app.R_Slider.Value;
            SimulateButtonPushed(app,event);
        end

        % Value changed function: L_Slider
        function L_SliderValueChanged(app, event)
            value = app.L_Slider.Value;
            SimulateButtonPushed(app,event);
        end

        % Value changed function: C_Slider
        function C_SliderValueChanged(app, event)
            value = app.C_Slider.Value;
            SimulateButtonPushed(app,event);
        end

        % Value changed function: w0_Slider
        function w0_SliderValueChanged(app, event)
            value = app.w0_Slider.Value;
            SimulateButtonPushed(app,event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_VR = uiaxes(app.UIFigure);
            title(app.UIAxes_VR, 'V_{R}')
            xlabel(app.UIAxes_VR, 'Time[sec]')
            ylabel(app.UIAxes_VR, 'Voltage[V]')
            zlabel(app.UIAxes_VR, 'Z')
            app.UIAxes_VR.YLim = [-1.2 1.2];
            app.UIAxes_VL = uiaxes(app.UIFigure);
            title(app.UIAxes_VL, 'V_{L}')
            xlabel(app.UIAxes_VL, 'Time[sec]')
            ylabel(app.UIAxes_VL, 'Voltage[V]')
            zlabel(app.UIAxes_VL, 'Z')
            app.UIAxes_VL.YLim = [-1.2 1.2];
            app.UIAxes_VC = uiaxes(app.UIFigure);
            title(app.UIAxes_VC, 'V_{C}')
            xlabel(app.UIAxes_VC, 'Time[sec]')
            ylabel(app.UIAxes_VC, 'Voltage[V]')
            zlabel(app.UIAxes_VC, 'Z')
            app.UIAxes_VC.YLim = [-1.2 1.2];
            app.UIAxes_Combined = uiaxes(app.UIFigure);
            title(app.UIAxes_Combined, 'Voltages on Components')
            xlabel(app.UIAxes_Combined, 'Time[sec]')
            ylabel(app.UIAxes_Combined, 'Voltage[V]')
            zlabel(app.UIAxes_Combined, 'Z')
            app.rlc_model = uiimage(app.UIFigure);
            app.rlc_model.ImageSource = 'Screenshot 2026-06-02 125723.png';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.L_Slider = uislider(app.UIFigure);
            app.L_Slider.Limits = [1 1000];
            app.L_Slider.MajorTicks = [1 250 500 750 1000];
            app.L_Slider.ValueChangedFcn = createCallbackFcn(app, @L_SliderValueChanged, true);
            app.L_Slider.ValueChangingFcn = createCallbackFcn(app, @L_SliderValueChanging, true);
            app.L_Slider.Value = 1;
            app.C_Slider = uislider(app.UIFigure);
            app.C_Slider.Limits = [1 1000];
            app.C_Slider.MajorTicks = [1 250 500 750 1000];
            app.C_Slider.ValueChangedFcn = createCallbackFcn(app, @C_SliderValueChanged, true);
            app.C_Slider.ValueChangingFcn = createCallbackFcn(app, @C_SliderValueChanging, true);
            app.C_Slider.Value = 1;
            app.w0_Slider = uislider(app.UIFigure);
            app.w0_Slider.Limits = [1 500];
            app.w0_Slider.MajorTicks = [1 100 200 300 400 500];
            app.w0_Slider.ValueChangedFcn = createCallbackFcn(app, @w0_SliderValueChanged, true);
            app.w0_Slider.ValueChangingFcn = createCallbackFcn(app, @w0_SliderValueChanging, true);
            app.w0_Slider.Value = 1;
            app.ResistcanceLabel = uilabel(app.UIFigure);
            app.ResistcanceLabel.Interpreter = 'html';
            app.ResistcanceLabel.Text = 'Resistcance[&#937]';
            app.R_Slider = uislider(app.UIFigure);
            app.R_Slider.Limits = [1 1000];
            app.R_Slider.MajorTicks = [1 250 500 750 1000];
            app.R_Slider.ValueChangedFcn = createCallbackFcn(app, @R_SliderValueChanged, true);
            app.R_Slider.ValueChangingFcn = createCallbackFcn(app, @R_SliderValueChanging, true);
            app.R_Slider.Value = 1;
            app.R_EditField = uieditfield(app.UIFigure, 'numeric');
            app.R_EditField.Limits = [1 1000];
            app.R_EditField.Value = 1;
            app.InductancemHLabel = uilabel(app.UIFigure);
            app.InductancemHLabel.Interpreter = 'html';
            app.InductancemHLabel.Text = 'Inductance[mH]';
            app.L_EditField = uieditfield(app.UIFigure, 'numeric');
            app.L_EditField.Limits = [1 1000];
            app.L_EditField.Value = 1;
            app.CapcitanceuFLabel = uilabel(app.UIFigure);
            app.CapcitanceuFLabel.Interpreter = 'html';
            app.CapcitanceuFLabel.Text = 'Capcitance[uF]';
            app.C_EditField = uieditfield(app.UIFigure, 'numeric');
            app.C_EditField.Limits = [1 1000];
            app.C_EditField.Value = 1;
            app.FrequencyradsecLabel = uilabel(app.UIFigure);
            app.FrequencyradsecLabel.Interpreter = 'html';
            app.FrequencyradsecLabel.Text = 'Frequency[<sup>rad</sup>&frasl;<sub>sec</sub>]';
            app.w0_EditField = uieditfield(app.UIFigure, 'numeric');
            app.w0_EditField.Limits = [1 500];
            app.w0_EditField.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Untitled.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = numerical_solution_series_rlc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end